classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        rawdatamatTab         matlab.ui.container.Tab
        ClassifierPanel       matlab.ui.container.Panel
        FNNButton             matlab.ui.control.Button
        RNNButton             matlab.ui.control.Button
        CNNButton             matlab.ui.control.Button
        GRNNButton            matlab.ui.control.Button
        AccuracyPanel         matlab.ui.container.Panel
        FNNEditFieldLabel     matlab.ui.control.Label
        FNNEditField          matlab.ui.control.NumericEditField
        RNNEditFieldLabel     matlab.ui.control.Label
        RNNEditField          matlab.ui.control.NumericEditField
        CNNEditFieldLabel     matlab.ui.control.Label
        CNNEditField          matlab.ui.control.NumericEditField
        GRNNEditFieldLabel    matlab.ui.control.Label
        GRNNEditField         matlab.ui.control.NumericEditField
        fs_datamatTab         matlab.ui.container.Tab
        ClassifierPanel_2     matlab.ui.container.Panel
        FNNButton_2           matlab.ui.control.Button
        RNNButton_2           matlab.ui.control.Button
        CNNButton_2           matlab.ui.control.Button
        GRNNButton_2          matlab.ui.control.Button
        AccuracyPanel_2       matlab.ui.container.Panel
        FNNEditField_2Label   matlab.ui.control.Label
        FNNEditField_2        matlab.ui.control.NumericEditField
        RNNEditField_2Label   matlab.ui.control.Label
        RNNEditField_2        matlab.ui.control.NumericEditField
        CNNEditField_2Label   matlab.ui.control.Label
        CNNEditField_2        matlab.ui.control.NumericEditField
        GRNNEditField_2Label  matlab.ui.control.Label
        GRNNEditField_2       matlab.ui.control.NumericEditField
        ca_datamatTab         matlab.ui.container.Tab
        ClassifierPanel_3     matlab.ui.container.Panel
        FNNButton_3           matlab.ui.control.Button
        RNNButton_3           matlab.ui.control.Button
        CNNButton_3           matlab.ui.control.Button
        GRNNButton_3          matlab.ui.control.Button
        AccuracyPanel_3       matlab.ui.container.Panel
        FNNEditField_3Label   matlab.ui.control.Label
        FNNEditField_3        matlab.ui.control.NumericEditField
        RNNEditField_3Label   matlab.ui.control.Label
        RNNEditField_3        matlab.ui.control.NumericEditField
        CNNEditField_3Label   matlab.ui.control.Label
        CNNEditField_3        matlab.ui.control.NumericEditField
        GRNNEditField_3Label  matlab.ui.control.Label
        GRNNEditField_3       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        raw = load("rawdata.mat") % import raw data
        fs = load("fs_data.mat") % import feature selection data
        ca = load("cardiacarrythmia.mat") % import finalize cardiac arrythmia data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FNNButton_2
        function FNNButton_2Pushed(app, event)
            feat = app.fs.f;
            label = app.fs.l;
            kfold=5; 
            Hiddens=3; 
            Maxepochs=60;
            
            % Layer
            if length(Hiddens)==1
                h1=Hiddens(1); net=patternnet(h1);
            elseif length(Hiddens)==2
              h1=Hiddens(1); h2=Hiddens(2); net=patternnet([h1 h2]);
            elseif length(Hiddens)==3
              h1=Hiddens(1); h2=Hiddens(2); h3=Hiddens(3); 
              net=patternnet([h1 h2 h3]); 
            end
            rng('default'); 
            
            % Divide data into k-folds
            fold=cvpartition(label,'kfold',kfold);
            
            % Pre
            pred2=[]; ytest2=[]; Afold=zeros(kfold,1); 
            
            % Neural network start
            for i=1:kfold
              % Call index of training & testing sets
                trainIdx=fold.training(i); testIdx=fold.test(i);
              % Call training & testing features and labels
              xtrain=feat(trainIdx,:); ytrain=label(trainIdx);
              xtest=feat(testIdx,:); ytest=label(testIdx);
              % Set Maximum epochs
              net.trainParam.epochs= Maxepochs;
              % Training model
              net=train(net,xtrain',dummyvar(ytrain)');
              % Perform testing
              pred=net(xtest'); 
              % Confusion matrix
              [~,con]=confusion(dummyvar(ytest)',pred);
              % Get accuracy for each fold
              Afold(i)=100*sum(diag(con))/sum(con(:));
              % Store temporary result for each fold
              pred2=[pred2(1:end,:),pred]; ytest2=[ytest2(1:end);ytest]; 
            end
            
            % Overall confusion matrix
            [~,confmat]=confusion(dummyvar(ytest2)',pred2); confmat=transpose(confmat);
            % Average accuracy over k-folds
            acc=mean(Afold);
            % Store results 
            NN.fold=Afold; NN.acc=acc; NN.con=confmat ;
            %fprintf('\n Classification Accuracy (NN): %g %%',acc);
            app.FNNEditField_2.Value = acc; 
        end

        % Button pushed function: FNNButton
        function FNNButtonPushed(app, event)
            feat = app.raw.f;
            label = app.raw.l;
            kfold=5; 
            Hiddens=3; 
            Maxepochs=60;
            
            % Layer
            if length(Hiddens)==1
                h1=Hiddens(1); net=patternnet(h1);
            elseif length(Hiddens)==2
              h1=Hiddens(1); h2=Hiddens(2); net=patternnet([h1 h2]);
            elseif length(Hiddens)==3
              h1=Hiddens(1); h2=Hiddens(2); h3=Hiddens(3); 
              net=patternnet([h1 h2 h3]); 
            end
            rng('default'); 
            
            % Divide data into k-folds
            fold=cvpartition(label,'kfold',kfold);
            
            % Pre
            pred2=[]; ytest2=[]; Afold=zeros(kfold,1); 
            
            % Neural network start
            for i=1:kfold
              % Call index of training & testing sets
                trainIdx=fold.training(i); testIdx=fold.test(i);
              % Call training & testing features and labels
              xtrain=feat(trainIdx,:); ytrain=label(trainIdx);
              xtest=feat(testIdx,:); ytest=label(testIdx);
              % Set Maximum epochs
              net.trainParam.epochs= Maxepochs;
              % Training model
              net=train(net,xtrain',dummyvar(ytrain)');
              % Perform testing
              pred=net(xtest'); 
              % Confusion matrix
              [~,con]=confusion(dummyvar(ytest)',pred);
              % Get accuracy for each fold
              Afold(i)=100*sum(diag(con))/sum(con(:));
              % Store temporary result for each fold
              pred2=[pred2(1:end,:),pred]; ytest2=[ytest2(1:end);ytest]; 
            end
            
            % Overall confusion matrix
            [~,confmat]=confusion(dummyvar(ytest2)',pred2); confmat=transpose(confmat);
            % Average accuracy over k-folds
            acc=mean(Afold);
            % Store results 
            NN.fold=Afold; NN.acc=acc; NN.con=confmat ;
            %fprintf('\n Classification Accuracy (NN): %g %%',acc);
            app.FNNEditField.Value = acc;
        end

        % Button pushed function: RNNButton
        function RNNButtonPushed(app, event)
            feat = app.raw.f;
            label = app.raw.l;
            kfold=5; 
            Hiddens=3; 
            Maxepochs=60;
            
            % Layer
            if length(Hiddens)==1
                h1=Hiddens(1); net=layrecnet(1:2,h1); 
            elseif length(Hiddens)==2
                h1=Hiddens(1); h2=Hiddens(2); net=layrecnet(1:2,[h1 h2]);
            elseif length(Hiddens)==3
                h1=Hiddens(1); h2=Hiddens(2); h3=Hiddens(3);
                net=layrecnet(1:2,[h1 h2 h3]);
            end
            rng('default'); 
            % Divide data into k-folds
            fold=cvpartition(label,'kfold',kfold);
            % Pre
            pred2=[]; ytest2=[]; Afold=zeros(kfold,1); 
            % Neural network start
            for i=1:kfold
              % Call index of training & testing sets
            	trainIdx=fold.training(i); testIdx=fold.test(i);
              % Call training & testing features and labels
              xtrain=feat(trainIdx,:); ytrain=label(trainIdx);
              xtest=feat(testIdx,:); ytest=label(testIdx);
              % Set Maximum epochs
              net.trainParam.epochs= Maxepochs;
              % Training model
              net=train(net,xtrain',dummyvar(ytrain)');
              % Perform testing
              pred=net(xtest');
              % Confusion matrix
              [~,con]=confusion(dummyvar(ytest)',pred);
              % Get accuracy for each fold
              Afold(i)=100*sum(diag(con))/sum(con(:));
              % Store temporary result for each fold
              pred2=[pred2(1:end,:),pred]; ytest2=[ytest2(1:end);ytest];
            end
            % Overall confusion matrix
            [~,confmat]=confusion(dummyvar(ytest2)',pred2); confmat=transpose(confmat);
            % Average accuracy over k-folds
            acc=mean(Afold);
            % Store results 
            RNN.fold=Afold; RNN.acc=acc; RNN.con=confmat; 
            %fprintf('\n Classification Accuracy (RNN): %g %%',acc);
            app.RNNEditField.Value = acc;
        end

        % Button pushed function: CNNButton
        function CNNButtonPushed(app, event)
            feat = app.raw.f;
            label = app.raw.l;
            kfold=5; 
            Hiddens=3; 
            Maxepochs=60;
            
            % Layer
            if length(Hiddens)==1
                h1=Hiddens(1); net=cascadeforwardnet(h1); 
            elseif length(Hiddens)==2
                h1=Hiddens(1); h2=Hiddens(2);
                net=cascadeforwardnet([h1 h2]);
            elseif length(Hiddens)==3
                h1=Hiddens(1); h2=Hiddens(2);
                h3=Hiddens(3); net=cascadeforwardnet([h1 h2 h3]);
            end
            rng('default');
            % Divide data into k-folds
            fold=cvpartition(label,'kfold',kfold); 
            
            % Pre
            pred2=[]; ytest2=[]; Afold=zeros(kfold,1); 
            
            % Neural network start
            for i=1:kfold
              % Call index of training & testing sets
              %trainIdx=fold.training(i); testIdx=fold.test(i);
              trainIdx=fold.training(i); testIdx=fold.test(i);
              % Call training & testing features and labels
              xtrain=feat(trainIdx,:); ytrain=label(trainIdx);
              xtest=feat(testIdx,:); ytest=label(testIdx);
              % Set Maximum epochs
              net.trainParam.epochs= Maxepochs; 
              % Training model
              net=train(net, xtrain', dummyvar(ytrain)');
              % Perform testing
              pred=net(xtest'); 
              % Confusion matrix
              [~,con]=confusion(dummyvar(ytest)',pred);
              % Get accuracy for each fold
              Afold(i)=100*sum(diag(con))/sum(con(:));
              % Store temporary result for each fold
              pred2=[pred2(1:end,:),pred]; ytest2=[ytest2(1:end);ytest];  
            end
            
            % Overall confusion matrix
            [~,confmat]=confusion(dummyvar(ytest2)',pred2); confmat=transpose(confmat);
            % Average accuracy over k-folds
            acc=mean(Afold); 
            % Store results 
            CFNN.fold=Afold; CFNN.acc=acc; CFNN.con=confmat; 
            %fprintf('\n Classification Accuracy (CFNN): %g %%',acc);
            app.CNNEditField.Value = acc;
        end

        % Button pushed function: GRNNButton
        function GRNNButtonPushed(app, event)
            feat = app.raw.f;
            label = app.raw.l;
            kfold=5; 
            nSpread = 1;
            
            rng('default'); 
            % Divide data into k-folds
            fold=cvpartition(label,'kfold',kfold);
            
            % Pre
            pred2=[]; ytest2=[]; Afold=zeros(kfold,1); 
            
            % Neural network start
            for i=1:kfold
              % Call index of training & testing sets
            	trainIdx=fold.training(i); testIdx=fold.test(i);
              % Call training & testing features and labels
              xtrain=feat(trainIdx,:); ytrain=label(trainIdx);
              xtest=feat(testIdx,:); ytest=label(testIdx);
              % Training the model
              net=newgrnn(xtrain',dummyvar(ytrain)',nSpread); 
              % Perform testing
              pred=net(xtest');
              % Confusion matrix
              [~,con]=confusion(dummyvar(ytest)',pred);
              % Get accuracy for each fold
              Afold(i)=100*sum(diag(con))/sum(con(:));
              % Store temporary result for each fold
              pred2=[pred2(1:end,:),pred]; ytest2=[ytest2(1:end);ytest];
            end
            
            % Overall confusion matrix
            [~,confmat]=confusion(dummyvar(ytest2)',pred2); confmat=transpose(confmat);
            % Average accuracy over k-folds
            acc=mean(Afold);
            % Store results 
            GRNN.fold=Afold; GRNN.acc=acc; GRNN.con=confmat; 
            %fprintf('\n Classification Accuracy (GRNN): %g %%',acc); 
            app.GRNNEditField.Value = acc;       
        end

        % Button pushed function: RNNButton_2
        function RNNButton_2Pushed(app, event)
            feat = app.fs.f;
            label = app.fs.l;
            kfold=5; 
            Hiddens=3; 
            Maxepochs=60;
            
            % Layer
            if length(Hiddens)==1
                h1=Hiddens(1); net=layrecnet(1:2,h1); 
            elseif length(Hiddens)==2
                h1=Hiddens(1); h2=Hiddens(2); net=layrecnet(1:2,[h1 h2]);
            elseif length(Hiddens)==3
                h1=Hiddens(1); h2=Hiddens(2); h3=Hiddens(3);
                net=layrecnet(1:2,[h1 h2 h3]);
            end
            rng('default'); 
            % Divide data into k-folds
            fold=cvpartition(label,'kfold',kfold);
            % Pre
            pred2=[]; ytest2=[]; Afold=zeros(kfold,1); 
            % Neural network start
            for i=1:kfold
              % Call index of training & testing sets
            	trainIdx=fold.training(i); testIdx=fold.test(i);
              % Call training & testing features and labels
              xtrain=feat(trainIdx,:); ytrain=label(trainIdx);
              xtest=feat(testIdx,:); ytest=label(testIdx);
              % Set Maximum epochs
              net.trainParam.epochs= Maxepochs;
              % Training model
              net=train(net,xtrain',dummyvar(ytrain)');
              % Perform testing
              pred=net(xtest');
              % Confusion matrix
              [~,con]=confusion(dummyvar(ytest)',pred);
              % Get accuracy for each fold
              Afold(i)=100*sum(diag(con))/sum(con(:));
              % Store temporary result for each fold
              pred2=[pred2(1:end,:),pred]; ytest2=[ytest2(1:end);ytest];
            end
            % Overall confusion matrix
            [~,confmat]=confusion(dummyvar(ytest2)',pred2); confmat=transpose(confmat);
            % Average accuracy over k-folds
            acc=mean(Afold);
            % Store results 
            RNN.fold=Afold; RNN.acc=acc; RNN.con=confmat; 
            %fprintf('\n Classification Accuracy (RNN): %g %%',acc);
            app.RNNEditField_2.Value = acc;
        end

        % Button pushed function: CNNButton_2
        function CNNButton_2Pushed(app, event)
             feat = app.fs.f;
            label = app.fs.l;
            kfold=5; 
            Hiddens=3; 
            Maxepochs=60;
            
            % Layer
            if length(Hiddens)==1
                h1=Hiddens(1); net=cascadeforwardnet(h1); 
            elseif length(Hiddens)==2
                h1=Hiddens(1); h2=Hiddens(2);
                net=cascadeforwardnet([h1 h2]);
            elseif length(Hiddens)==3
                h1=Hiddens(1); h2=Hiddens(2);
                h3=Hiddens(3); net=cascadeforwardnet([h1 h2 h3]);
            end
            rng('default');
            % Divide data into k-folds
            fold=cvpartition(label,'kfold',kfold); 
            
            % Pre
            pred2=[]; ytest2=[]; Afold=zeros(kfold,1); 
            
            % Neural network start
            for i=1:kfold
              % Call index of training & testing sets
              %trainIdx=fold.training(i); testIdx=fold.test(i);
              trainIdx=fold.training(i); testIdx=fold.test(i);
              % Call training & testing features and labels
              xtrain=feat(trainIdx,:); ytrain=label(trainIdx);
              xtest=feat(testIdx,:); ytest=label(testIdx);
              % Set Maximum epochs
              net.trainParam.epochs= Maxepochs; 
              % Training model
              net=train(net, xtrain', dummyvar(ytrain)');
              % Perform testing
              pred=net(xtest'); 
              % Confusion matrix
              [~,con]=confusion(dummyvar(ytest)',pred);
              % Get accuracy for each fold
              Afold(i)=100*sum(diag(con))/sum(con(:));
              % Store temporary result for each fold
              pred2=[pred2(1:end,:),pred]; ytest2=[ytest2(1:end);ytest];  
            end
            
            % Overall confusion matrix
            [~,confmat]=confusion(dummyvar(ytest2)',pred2); confmat=transpose(confmat);
            % Average accuracy over k-folds
            acc=mean(Afold); 
            % Store results 
            CFNN.fold=Afold; CFNN.acc=acc; CFNN.con=confmat; 
            %fprintf('\n Classification Accuracy (CFNN): %g %%',acc);
            app.CNNEditField_2.Value = acc;
        end

        % Button pushed function: GRNNButton_2
        function GRNNButton_2Pushed(app, event)
             feat = app.fs.f;
            label = app.fs.l;
            kfold=5; 
            nSpread = 1;
            
            rng('default'); 
            % Divide data into k-folds
            fold=cvpartition(label,'kfold',kfold);
            
            % Pre
            pred2=[]; ytest2=[]; Afold=zeros(kfold,1); 
            
            % Neural network start
            for i=1:kfold
              % Call index of training & testing sets
            	trainIdx=fold.training(i); testIdx=fold.test(i);
              % Call training & testing features and labels
              xtrain=feat(trainIdx,:); ytrain=label(trainIdx);
              xtest=feat(testIdx,:); ytest=label(testIdx);
              % Training the model
              net=newgrnn(xtrain',dummyvar(ytrain)',nSpread); 
              % Perform testing
              pred=net(xtest');
              % Confusion matrix
              [~,con]=confusion(dummyvar(ytest)',pred);
              % Get accuracy for each fold
              Afold(i)=100*sum(diag(con))/sum(con(:));
              % Store temporary result for each fold
              pred2=[pred2(1:end,:),pred]; ytest2=[ytest2(1:end);ytest];
            end
            
            % Overall confusion matrix
            [~,confmat]=confusion(dummyvar(ytest2)',pred2); confmat=transpose(confmat);
            % Average accuracy over k-folds
            acc=mean(Afold);
            % Store results 
            GRNN.fold=Afold; GRNN.acc=acc; GRNN.con=confmat; 
            %fprintf('\n Classification Accuracy (GRNN): %g %%',acc); 
            app.GRNNEditField_2.Value = acc;      
        end

        % Button pushed function: FNNButton_3
        function FNNButton_3Pushed(app, event)
           feat = app.ca.f;
           label = app.ca.l;
            kfold=5; 
            Hiddens=3; 
            Maxepochs=60;
            
            % Layer
            if length(Hiddens)==1
                h1=Hiddens(1); net=patternnet(h1);
            elseif length(Hiddens)==2
              h1=Hiddens(1); h2=Hiddens(2); net=patternnet([h1 h2]);
            elseif length(Hiddens)==3
              h1=Hiddens(1); h2=Hiddens(2); h3=Hiddens(3); 
              net=patternnet([h1 h2 h3]); 
            end
            rng('default'); 
            
            % Divide data into k-folds
            fold=cvpartition(label,'kfold',kfold);
            
            % Pre
            pred2=[]; ytest2=[]; Afold=zeros(kfold,1); 
            
            % Neural network start
            for i=1:kfold
              % Call index of training & testing sets
                trainIdx=fold.training(i); testIdx=fold.test(i);
              % Call training & testing features and labels
              xtrain=feat(trainIdx,:); ytrain=label(trainIdx);
              xtest=feat(testIdx,:); ytest=label(testIdx);
              % Set Maximum epochs
              net.trainParam.epochs= Maxepochs;
              % Training model
              net=train(net,xtrain',dummyvar(ytrain)');
              % Perform testing
              pred=net(xtest'); 
              % Confusion matrix
              [~,con]=confusion(dummyvar(ytest)',pred);
              % Get accuracy for each fold
              Afold(i)=100*sum(diag(con))/sum(con(:));
              % Store temporary result for each fold
              pred2=[pred2(1:end,:),pred]; ytest2=[ytest2(1:end);ytest]; 
            end
            
            % Overall confusion matrix
            [~,confmat]=confusion(dummyvar(ytest2)',pred2); confmat=transpose(confmat);
            % Average accuracy over k-folds
            acc=mean(Afold);
            % Store results 
            NN.fold=Afold; NN.acc=acc; NN.con=confmat ;
            %fprintf('\n Classification Accuracy (NN): %g %%',acc);
            app.FNNEditField_3.Value = acc; 
        end

        % Button pushed function: RNNButton_3
        function RNNButton_3Pushed(app, event)
            feat = app.ca.f;
            label = app.ca.l;
            kfold=5; 
            Hiddens=3; 
            Maxepochs=60;
            
            % Layer
            if length(Hiddens)==1
                h1=Hiddens(1); net=layrecnet(1:2,h1); 
            elseif length(Hiddens)==2
                h1=Hiddens(1); h2=Hiddens(2); net=layrecnet(1:2,[h1 h2]);
            elseif length(Hiddens)==3
                h1=Hiddens(1); h2=Hiddens(2); h3=Hiddens(3);
                net=layrecnet(1:2,[h1 h2 h3]);
            end
            rng('default'); 
            % Divide data into k-folds
            fold=cvpartition(label,'kfold',kfold);
            % Pre
            pred2=[]; ytest2=[]; Afold=zeros(kfold,1); 
            % Neural network start
            for i=1:kfold
              % Call index of training & testing sets
            	trainIdx=fold.training(i); testIdx=fold.test(i);
              % Call training & testing features and labels
              xtrain=feat(trainIdx,:); ytrain=label(trainIdx);
              xtest=feat(testIdx,:); ytest=label(testIdx);
              % Set Maximum epochs
              net.trainParam.epochs= Maxepochs;
              % Training model
              net=train(net,xtrain',dummyvar(ytrain)');
              % Perform testing
              pred=net(xtest');
              % Confusion matrix
              [~,con]=confusion(dummyvar(ytest)',pred);
              % Get accuracy for each fold
              Afold(i)=100*sum(diag(con))/sum(con(:));
              % Store temporary result for each fold
              pred2=[pred2(1:end,:),pred]; ytest2=[ytest2(1:end);ytest];
            end
            % Overall confusion matrix
            [~,confmat]=confusion(dummyvar(ytest2)',pred2); confmat=transpose(confmat);
            % Average accuracy over k-folds
            acc=mean(Afold);
            % Store results 
            RNN.fold=Afold; RNN.acc=acc; RNN.con=confmat; 
            %fprintf('\n Classification Accuracy (RNN): %g %%',acc);
            app.RNNEditField_3.Value = acc;
        end

        % Button pushed function: CNNButton_3
        function CNNButton_3Pushed(app, event)
            feat = app.ca.f;
            label = app.ca.l;
            kfold=5; 
            Hiddens=3; 
            Maxepochs=60;
            
            % Layer
            if length(Hiddens)==1
                h1=Hiddens(1); net=cascadeforwardnet(h1); 
            elseif length(Hiddens)==2
                h1=Hiddens(1); h2=Hiddens(2);
                net=cascadeforwardnet([h1 h2]);
            elseif length(Hiddens)==3
                h1=Hiddens(1); h2=Hiddens(2);
                h3=Hiddens(3); net=cascadeforwardnet([h1 h2 h3]);
            end
            rng('default');
            % Divide data into k-folds
            fold=cvpartition(label,'kfold',kfold); 
            
            % Pre
            pred2=[]; ytest2=[]; Afold=zeros(kfold,1); 
            
            % Neural network start
            for i=1:kfold
              % Call index of training & testing sets
              %trainIdx=fold.training(i); testIdx=fold.test(i);
              trainIdx=fold.training(i); testIdx=fold.test(i);
              % Call training & testing features and labels
              xtrain=feat(trainIdx,:); ytrain=label(trainIdx);
              xtest=feat(testIdx,:); ytest=label(testIdx);
              % Set Maximum epochs
              net.trainParam.epochs= Maxepochs; 
              % Training model
              net=train(net, xtrain', dummyvar(ytrain)');
              % Perform testing
              pred=net(xtest'); 
              % Confusion matrix
              [~,con]=confusion(dummyvar(ytest)',pred);
              % Get accuracy for each fold
              Afold(i)=100*sum(diag(con))/sum(con(:));
              % Store temporary result for each fold
              pred2=[pred2(1:end,:),pred]; ytest2=[ytest2(1:end);ytest];  
            end
            
            % Overall confusion matrix
            [~,confmat]=confusion(dummyvar(ytest2)',pred2); confmat=transpose(confmat);
            % Average accuracy over k-folds
            acc=mean(Afold); 
            % Store results 
            CFNN.fold=Afold; CFNN.acc=acc; CFNN.con=confmat; 
            %fprintf('\n Classification Accuracy (CFNN): %g %%',acc);
            app.CNNEditField_3.Value = acc; 
        end

        % Button pushed function: GRNNButton_3
        function GRNNButton_3Pushed(app, event)
              feat = app.ca.f;
            label = app.ca.l;
            kfold=5; 
            nSpread = 1;
            
            rng('default'); 
            % Divide data into k-folds
            fold=cvpartition(label,'kfold',kfold);
            
            % Pre
            pred2=[]; ytest2=[]; Afold=zeros(kfold,1); 
            
            % Neural network start
            for i=1:kfold
              % Call index of training & testing sets
            	trainIdx=fold.training(i); testIdx=fold.test(i);
              % Call training & testing features and labels
              xtrain=feat(trainIdx,:); ytrain=label(trainIdx);
              xtest=feat(testIdx,:); ytest=label(testIdx);
              % Training the model
              net=newgrnn(xtrain',dummyvar(ytrain)',nSpread); 
              % Perform testing
              pred=net(xtest');
              % Confusion matrix
              [~,con]=confusion(dummyvar(ytest)',pred);
              % Get accuracy for each fold
              Afold(i)=100*sum(diag(con))/sum(con(:));
              % Store temporary result for each fold
              pred2=[pred2(1:end,:),pred]; ytest2=[ytest2(1:end);ytest];
            end
            
            % Overall confusion matrix
            [~,confmat]=confusion(dummyvar(ytest2)',pred2); confmat=transpose(confmat);
            % Average accuracy over k-folds
            acc=mean(Afold);
            % Store results 
            GRNN.fold=Afold; GRNN.acc=acc; GRNN.con=confmat; 
            %fprintf('\n Classification Accuracy (GRNN): %g %%',acc); 
            app.GRNNEditField_3.Value = acc;    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.rawdatamatTab = uitab(app.TabGroup);
            app.rawdatamatTab.Title = 'rawdata.mat';
            app.ClassifierPanel = uipanel(app.rawdatamatTab);
            app.ClassifierPanel.Title = 'Classifier';
            app.FNNButton = uibutton(app.ClassifierPanel, 'push');
            app.FNNButton.ButtonPushedFcn = createCallbackFcn(app, @FNNButtonPushed, true);
            app.FNNButton.Text = 'FNN';
            app.RNNButton = uibutton(app.ClassifierPanel, 'push');
            app.RNNButton.ButtonPushedFcn = createCallbackFcn(app, @RNNButtonPushed, true);
            app.RNNButton.Text = 'RNN';
            app.CNNButton = uibutton(app.ClassifierPanel, 'push');
            app.CNNButton.ButtonPushedFcn = createCallbackFcn(app, @CNNButtonPushed, true);
            app.CNNButton.Text = 'CNN';
            app.GRNNButton = uibutton(app.ClassifierPanel, 'push');
            app.GRNNButton.ButtonPushedFcn = createCallbackFcn(app, @GRNNButtonPushed, true);
            app.GRNNButton.Text = 'GRNN';
            app.AccuracyPanel = uipanel(app.rawdatamatTab);
            app.AccuracyPanel.Title = 'Accuracy';
            app.FNNEditFieldLabel = uilabel(app.AccuracyPanel);
            app.FNNEditFieldLabel.Text = 'FNN ';
            app.FNNEditField = uieditfield(app.AccuracyPanel, 'numeric');
            app.RNNEditFieldLabel = uilabel(app.AccuracyPanel);
            app.RNNEditFieldLabel.Text = 'RNN';
            app.RNNEditField = uieditfield(app.AccuracyPanel, 'numeric');
            app.CNNEditFieldLabel = uilabel(app.AccuracyPanel);
            app.CNNEditFieldLabel.Text = 'CNN';
            app.CNNEditField = uieditfield(app.AccuracyPanel, 'numeric');
            app.GRNNEditFieldLabel = uilabel(app.AccuracyPanel);
            app.GRNNEditFieldLabel.Text = 'GRNN';
            app.GRNNEditField = uieditfield(app.AccuracyPanel, 'numeric');
            app.fs_datamatTab = uitab(app.TabGroup);
            app.fs_datamatTab.Title = 'fs_data.mat';
            app.ClassifierPanel_2 = uipanel(app.fs_datamatTab);
            app.ClassifierPanel_2.Title = 'Classifier';
            app.FNNButton_2 = uibutton(app.ClassifierPanel_2, 'push');
            app.FNNButton_2.ButtonPushedFcn = createCallbackFcn(app, @FNNButton_2Pushed, true);
            app.FNNButton_2.Text = 'FNN';
            app.RNNButton_2 = uibutton(app.ClassifierPanel_2, 'push');
            app.RNNButton_2.ButtonPushedFcn = createCallbackFcn(app, @RNNButton_2Pushed, true);
            app.RNNButton_2.Text = 'RNN';
            app.CNNButton_2 = uibutton(app.ClassifierPanel_2, 'push');
            app.CNNButton_2.ButtonPushedFcn = createCallbackFcn(app, @CNNButton_2Pushed, true);
            app.CNNButton_2.Text = 'CNN';
            app.GRNNButton_2 = uibutton(app.ClassifierPanel_2, 'push');
            app.GRNNButton_2.ButtonPushedFcn = createCallbackFcn(app, @GRNNButton_2Pushed, true);
            app.GRNNButton_2.Text = 'GRNN';
            app.AccuracyPanel_2 = uipanel(app.fs_datamatTab);
            app.AccuracyPanel_2.Title = 'Accuracy';
            app.FNNEditField_2Label = uilabel(app.AccuracyPanel_2);
            app.FNNEditField_2Label.Text = 'FNN ';
            app.FNNEditField_2 = uieditfield(app.AccuracyPanel_2, 'numeric');
            app.RNNEditField_2Label = uilabel(app.AccuracyPanel_2);
            app.RNNEditField_2Label.Text = 'RNN';
            app.RNNEditField_2 = uieditfield(app.AccuracyPanel_2, 'numeric');
            app.CNNEditField_2Label = uilabel(app.AccuracyPanel_2);
            app.CNNEditField_2Label.Text = 'CNN';
            app.CNNEditField_2 = uieditfield(app.AccuracyPanel_2, 'numeric');
            app.GRNNEditField_2Label = uilabel(app.AccuracyPanel_2);
            app.GRNNEditField_2Label.Text = 'GRNN';
            app.GRNNEditField_2 = uieditfield(app.AccuracyPanel_2, 'numeric');
            app.ca_datamatTab = uitab(app.TabGroup);
            app.ca_datamatTab.Title = 'ca_data.mat';
            app.ClassifierPanel_3 = uipanel(app.ca_datamatTab);
            app.ClassifierPanel_3.Title = 'Classifier';
            app.FNNButton_3 = uibutton(app.ClassifierPanel_3, 'push');
            app.FNNButton_3.ButtonPushedFcn = createCallbackFcn(app, @FNNButton_3Pushed, true);
            app.FNNButton_3.Text = 'FNN';
            app.RNNButton_3 = uibutton(app.ClassifierPanel_3, 'push');
            app.RNNButton_3.ButtonPushedFcn = createCallbackFcn(app, @RNNButton_3Pushed, true);
            app.RNNButton_3.Text = 'RNN';
            app.CNNButton_3 = uibutton(app.ClassifierPanel_3, 'push');
            app.CNNButton_3.ButtonPushedFcn = createCallbackFcn(app, @CNNButton_3Pushed, true);
            app.CNNButton_3.Text = 'CNN';
            app.GRNNButton_3 = uibutton(app.ClassifierPanel_3, 'push');
            app.GRNNButton_3.ButtonPushedFcn = createCallbackFcn(app, @GRNNButton_3Pushed, true);
            app.GRNNButton_3.Text = 'GRNN';
            app.AccuracyPanel_3 = uipanel(app.ca_datamatTab);
            app.AccuracyPanel_3.Title = 'Accuracy';
            app.FNNEditField_3Label = uilabel(app.AccuracyPanel_3);
            app.FNNEditField_3Label.Text = 'FNN ';
            app.FNNEditField_3 = uieditfield(app.AccuracyPanel_3, 'numeric');
            app.RNNEditField_3Label = uilabel(app.AccuracyPanel_3);
            app.RNNEditField_3Label.Text = 'RNN';
            app.RNNEditField_3 = uieditfield(app.AccuracyPanel_3, 'numeric');
            app.CNNEditField_3Label = uilabel(app.AccuracyPanel_3);
            app.CNNEditField_3Label.Text = 'CNN';
            app.CNNEditField_3 = uieditfield(app.AccuracyPanel_3, 'numeric');
            app.GRNNEditField_3Label = uilabel(app.AccuracyPanel_3);
            app.GRNNEditField_3Label.Text = 'GRNN';
            app.GRNNEditField_3 = uieditfield(app.AccuracyPanel_3, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end